classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image        matlab.ui.control.Image
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button       matlab.ui.control.RadioButton
        Button_2     matlab.ui.control.RadioButton
        Button_3     matlab.ui.control.RadioButton
        Label        matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.Slider.Value;
            % flag = 1;
            switch app.ButtonGroup.SelectedObject.Text
                case "之"
                    flag = 1;
                case "道"
                    flag = 2;
                case "以"
                    flag = 3;
            end
            res = getDMSimage(flag,value);
            app.Image.ImageSource = res;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            switch app.ButtonGroup.SelectedObject.Text
                case "之"
                    flag = 1;
                case "道"
                    flag = 2;
                case "以"
                    flag = 3;
            end
            res = getDMSimage(flag,value);
            app.Image.ImageSource = res;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            value = app.Slider.Value;
            switch app.ButtonGroup.SelectedObject.Text
                case "之"
                    flag = 1;
                case "道"
                    flag = 2;
                case "以"
                    flag = 3;
            end
            res = getDMSimage(flag,value);
            app.Image.ImageSource = res;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '使用的汉字样本';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '之';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '道';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '以';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '使用的样本数量占比';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0.1 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end